classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        EncodeTab              matlab.ui.container.Tab
        OutputLabel            matlab.ui.control.Label
        RunButton              matlab.ui.control.Button
        modeSelect             matlab.ui.control.DropDown
        CompressionAlgorithmDropDownLabel  matlab.ui.control.Label
        EfficiencyXXxxxxLabel  matlab.ui.control.Label
        input                  matlab.ui.control.EditField
        InputEditFieldLabel    matlab.ui.control.Label
        DecodeTab              matlab.ui.container.Tab
    end


    methods (Access = private)

        function coded = lempelZivEnco(app, src)
            % Lempel-Ziv
            % Steps:
            % 1. Identify unique sequences
            %       1.5 Assign a binary number to each symbol

            % 2. Assign (Prefix index, Suffix) [in binary] pairs to
            %    said sequences.
            %       2.1 Get prefix binary
            %       2.2 Get suffix binary

            % 3. Append both parts of the pair to make a larger binary
            %    number.

            % 4. For each pair, find the maximum possible value of the
            %    number the pair makes.

            % 5. Find the number of bits needed to represent that
            %    number.

            % 6. The code for each symbol is the binary pair we made
            % earlier.

            uniqueSeqArr = getUniqueSeq(app, src);

            uniqueSymbols = unique(src);

            pairs = getPrefixSuffixPair(app, uniqueSeqArr, uniqueSymbols);

            maxPairs = getMaxPairs(app, uniqueSeqArr, uniqueSymbols);
        end

        function dict = getUniqueSeq(~, src)
            % Turns our input string into an array of unique sequences.
            dict = []; % This will keep hold of our unique sequences.
            letterIDX = 1;
            while letterIDX <= length(src)

                seqEndIDX = letterIDX; % Init

                % We use cell2mat because extractBetween returns a cell
                % array instead of a normal char vector.
                seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));

                while ismember(seq, dict)
                    if(seqEndIDX == length(src))
                        break
                    end
                    seqEndIDX = seqEndIDX + 1;
                    seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));
                end

                dict = [dict, convertCharsToStrings(seq)];
                letterIDX = seqEndIDX+1;
            end
        end


        function pairs = getPrefixSuffixPair(~, seqArr, symbols)
            pairs = zeros(1, length(seqArr));
            for idx = 1:length(seqArr)

                seq = char(seqArr(idx));
                suffix = seq(end);

                % We need to find the location of the prefix in our
                % dictionary.

                if strlength(seq) == 1
                    prefixBin = 0;
                else
                    prefix = seq(1:end-1);
                    prefixBin = dec2bin(find(seqArr == prefix));
                end


                % When we try to find a letter in the uniques array
                % we get an integer. Turn that integer to binary and voila.
                suffixBin = pad(dec2bin(strfind(symbols, suffix)-1), ...
                    ceil(log2(numel(symbols))), "left", '0');


                pairs(idx) = convertCharsToStrings(strcat(num2str(prefixBin), num2str(suffixBin)));
            end

        end

        function maxPairs = getMaxPairs(~, seqs, uniqueSyms)
            noSyms = numel(seqs);
            maxPairs = zeros(1, noSyms);
            maxSym = length(uniqueSyms);
            for idx = 1:length(maxPairs)
                
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            src = app.input.Value; % Input source text

            if app.modeSelect.Value == "Adaptive Arithmetic Coding"
                % Add ADA ari here
                uialert(app.UIFigure,'Adaptive Arithmetic Here',"MSG");
            else
                app.OutputLabel.Text = app.lempelZivEnco(src);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EncodeTab = uitab(app.TabGroup);
            app.EncodeTab.Title = 'Encode';
            app.InputEditFieldLabel = uilabel(app.EncodeTab);
            app.InputEditFieldLabel.Text = {'Input'; ''};
            app.input = uieditfield(app.EncodeTab, 'text');
            app.input.Value = 'abacbaccbaaccbbacbabca';
            app.EfficiencyXXxxxxLabel = uilabel(app.EncodeTab);
            app.EfficiencyXXxxxxLabel.Text = 'Efficiency = XX.xxxx%';
            app.CompressionAlgorithmDropDownLabel = uilabel(app.EncodeTab);
            app.CompressionAlgorithmDropDownLabel.Text = 'Compression Algorithm';
            app.modeSelect = uidropdown(app.EncodeTab);
            app.modeSelect.Items = {'Adaptive Arithmetic Coding', 'Lempel-Ziv Coding'};
            app.modeSelect.Value = 'Lempel-Ziv Coding';
            app.RunButton = uibutton(app.EncodeTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputLabel = uilabel(app.EncodeTab);
            app.OutputLabel.Text = 'Output:';
            app.DecodeTab = uitab(app.TabGroup);
            app.DecodeTab.Title = 'Decode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end